classdef guisimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Switch_7  matlab.ui.control.Switch
        Switch_6  matlab.ui.control.Switch
        Switch_5  matlab.ui.control.Switch
        Switch_4  matlab.ui.control.Switch
        Switch_3  matlab.ui.control.Switch
        Switch_2  matlab.ui.control.Switch
        Switch    matlab.ui.control.Switch
        Lamp_7    matlab.ui.control.Lamp
        Lamp_6    matlab.ui.control.Lamp
        Lamp_5    matlab.ui.control.Lamp
        Lamp_4    matlab.ui.control.Lamp
        Lamp_3    matlab.ui.control.Lamp
        Lamp_2    matlab.ui.control.Lamp
        Lamp      matlab.ui.control.Lamp
        Image     matlab.ui.control.Image
    end

    
    properties (Access = private)
        Gen_1
        Gen_2
        Gen_3
        Gen_4
        Breaker_1
        Breaker_2
        Motor_1
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.Gen_1 = value;
            switch value
                case 'Off'
                    app.Lamp.Color = 'Red';
                case 'On'
                    app.Lamp.Color = 'Green';
            end

        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            app.Gen_2 = value;
            switch value
                case 'Off'
                    app.Lamp_2.Color = 'Red';
                case 'On'
                    app.Lamp_2.Color = 'Green';
            end
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            app.Gen_3 = value;
            switch value
                case 'Off'
                    app.Lamp_3.Color = 'Red';
                case 'On'
                    app.Lamp_3.Color = 'Green';
            end
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            app.Gen_4 = value;
            switch value
                case 'Off'
                    app.Lamp_4.Color = 'Red';
                case 'On'
                    app.Lamp_4.Color = 'Green';
            end
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            app.Breaker_1 = value;
            switch value
                case 'Open'
                    app.Lamp_5.Color = 'Red';
                case 'Close'
                    app.Lamp_5.Color = 'Green';
            end
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
            value = app.Switch_6.Value;
            app.Breaker_2 = value;
            switch value
                case 'Open'
                    app.Lamp_6.Color = 'Red';
                case 'Close'
                    app.Lamp_6.Color = 'Green';
            end
        end

        % Value changed function: Switch_7
        function Switch_7ValueChanged(app, event)
            value = app.Switch_7.Value;
            app.Motor_1 = value;
            if strcmp('Close',app.Breaker_1) 
                if  strcmp('On',app.Gen_1) || strcmp('On',app.Gen_2)
                    switch value
                        case 'Off'
                            app.Lamp_7.Color = 'Red';
                            
                        case 'On'
                            app.Lamp_7.Color = 'Green';
                    end
                else
                    app.Lamp_7.Color = 'Red';
                end
            elseif strcmp('Close',app.Breaker_2) 
                if  strcmp('On',app.Gen_3) || strcmp('On',app.Gen_4)
                    switch value
                        case 'Off'
                            app.Lamp_7.Color = 'Red';
                        case 'On'
                            app.Lamp_7.Color = 'Green';
                    end
                else
                    app.Lamp_7.Color = 'Red';
                end
            else
                app.Lamp_7.Color = 'Red';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Symbols', 'Sample 2.jpg');
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_3 = uiswitch(app.UIFigure, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_4 = uiswitch(app.UIFigure, 'slider');
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_5 = uiswitch(app.UIFigure, 'slider');
            app.Switch_5.Items = {'Close', 'Open'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Value = 'Open';
            app.Switch_6 = uiswitch(app.UIFigure, 'slider');
            app.Switch_6.Items = {'Close', 'Open'};
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Switch_6.Value = 'Open';
            app.Switch_7 = uiswitch(app.UIFigure, 'slider');
            app.Switch_7.ValueChangedFcn = createCallbackFcn(app, @Switch_7ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guisimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end